classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        FocusButtonGroup  matlab.ui.container.ButtonGroup
        BackgroundButton  matlab.ui.control.RadioButton
        ForegroundButton  matlab.ui.control.RadioButton
        ASlider           matlab.ui.control.Slider
        ASliderLabel      matlab.ui.control.Label
        EVLabel           matlab.ui.control.Label
        EVValueLabel      matlab.ui.control.Label
        ISOSlider         matlab.ui.control.Slider
        ISOSliderLabel    matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        defaultButton     matlab.ui.control.StateButton
        PhotoButtonGroup  matlab.ui.container.ButtonGroup
        OptionalButton    matlab.ui.control.RadioButton
        ExampleButton     matlab.ui.control.RadioButton
        Image             matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         example_foreground_path = "lounge-hdr-foreground.png"
         example_background_path = "lounge-hdr-background.png"
         ISO = 1100;
         A = 1.8;
         S = 28;
         k = 9;
    end
    
    
    methods (Access = private)
        
        function improcess(app, photoobject, focusobject)
            background_path = "";
            foreground_path = "";
            helpdlg(string(app.BackgroundButton.Value));
            if photoobject == "Example"
                background_path = app.example_background_path;
                foreground_path = app.example_foreground_path;
            end
            background = imread(background_path);
            foreground = imread(foreground_path);
            %helpdlg(class(background));
            app.Image.ImageSource = background;
            %im2double(background);
            %imshow(background);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            DefaultChanged(app, 0);
        end

        % Value changing function: ISOSlider
        function ISOChanging(app, event)
            changingValue = event.Value;
            %change the EV value
            app.EVValueLabel.Text = string(round(changingValue));
        end

        % Value changed function: ISOSlider
        function ISOChanged(app, event)
            value = app.ISOSlider.Value;
            %recalculate and reprocess the photo
            
        end

        % Selection changed function: PhotoButtonGroup
        function PhotoChanged(app, event)
            selectedButton = app.PhotoButtonGroup.SelectedObject;
            
        end

        % Selection changed function: FocusButtonGroup
        function FocusChanged(app, event)
            selectedButton = app.FocusButtonGroup.SelectedObject;
            
        end

        % Value changed function: defaultButton
        function DefaultChanged(app, event)
            %value = app.defaultButton.Value;
            improcess(app, "Example"...
                , "Background");

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ISOSliderLabel = uilabel(app.LeftPanel);
            app.ISOSliderLabel.Text = 'ISO';
            app.ISOSlider = uislider(app.LeftPanel);
            app.ISOSlider.Limits = [100 6400];
            app.ISOSlider.ValueChangedFcn = createCallbackFcn(app, @ISOChanged, true);
            app.ISOSlider.ValueChangingFcn = createCallbackFcn(app, @ISOChanging, true);
            app.ISOSlider.Value = 100;
            app.EVValueLabel = uilabel(app.LeftPanel);
            app.EVValueLabel.Text = '100';
            app.EVLabel = uilabel(app.LeftPanel);
            app.EVLabel.Text = 'EV';
            app.ASliderLabel = uilabel(app.LeftPanel);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.LeftPanel);
            app.FocusButtonGroup = uibuttongroup(app.LeftPanel);
            app.FocusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FocusChanged, true);
            app.FocusButtonGroup.Title = 'Focus';
            app.ForegroundButton = uiradiobutton(app.FocusButtonGroup);
            app.ForegroundButton.Text = 'Foreground';
            app.ForegroundButton.Value = true;
            app.BackgroundButton = uiradiobutton(app.FocusButtonGroup);
            app.BackgroundButton.Text = 'Background';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.PhotoButtonGroup = uibuttongroup(app.RightPanel);
            app.PhotoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhotoChanged, true);
            app.PhotoButtonGroup.Title = 'Photo';
            app.ExampleButton = uiradiobutton(app.PhotoButtonGroup);
            app.ExampleButton.Text = 'Example';
            app.ExampleButton.Value = true;
            app.OptionalButton = uiradiobutton(app.PhotoButtonGroup);
            app.OptionalButton.Text = 'Optional';
            app.defaultButton = uibutton(app.RightPanel, 'state');
            app.defaultButton.ValueChangedFcn = createCallbackFcn(app, @DefaultChanged, true);
            app.defaultButton.Text = 'default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end