classdef DFT_2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Slider3                matlab.ui.control.Slider
        Slider3Label           matlab.ui.control.Label
        Slider2                matlab.ui.control.Slider
        Slider2Label           matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        Slider_y               matlab.ui.control.Slider
        Position_yLabel        matlab.ui.control.Label
        Slider_x               matlab.ui.control.Slider
        Position_xLabel        matlab.ui.control.Label
        WaveSelectButtonGroup  matlab.ui.container.ButtonGroup
        GaborButton            matlab.ui.control.RadioButton
        GaussButton            matlab.ui.control.RadioButton
        RectangleButton        matlab.ui.control.RadioButton
        SineButton             matlab.ui.control.RadioButton
        DeltaButton            matlab.ui.control.RadioButton
        RightPanel             matlab.ui.container.Panel
        AmplitudeFigureLabel   matlab.ui.control.Label
        PhaseFigureLabel       matlab.ui.control.Label
        OriginFigureLabel      matlab.ui.control.Label
        Image_phase_3          matlab.ui.control.Image
        Image_ampli_3          matlab.ui.control.Image
        Image_orgin_3          matlab.ui.control.Image
        Image_phase_2          matlab.ui.control.Image
        Image_ampli_2          matlab.ui.control.Image
        Image_origin_2         matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Wavetype;
        Origin_fig_2;
        % parameter of Delta function
        position_x;
        position_y;
        % parameter of Sine function
        angle;
        frequency;
        phase;
    end
    
    methods (Access = private)
        
        function Wave_generate(app)
            fig = zeros(256, 256);
            switch app.Wavetype
                case "Delta"
                    fig(app.position_x, app.position_y) = 1;
                case "Sine"

            end
            app.Origin_fig_2 = fig;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.Wavetype = "Delta";
            app.position_x = 128;
            app.position_y = 128;
        end

        % Selection changed function: WaveSelectButtonGroup
        function WaveChanged(app, event)
            selectedButton = app.WaveSelectButtonGroup.SelectedObject;
            switch selectedButton 
                case app.DeltaButton
                    app.Wavetype = "Delta";
                    app.Wave_generate();
                case app.SineButton
                    app.Wavetype = "Sine";
                    app.Slider_x.Visible = false;
                    app.Wave_generate();
                case app.RectangleButton
                    app.Wavetype = "Rectangle";
                    app.Wave_generate();
                case app.GaussButton
                    app.Wavetype = "Gauss";
                    
                case app.Gabor
                    app.Wavetype = "Gabor";

            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {460, 460};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WaveSelectButtonGroup = uibuttongroup(app.LeftPanel);
            app.WaveSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaveChanged, true);
            app.WaveSelectButtonGroup.Title = 'Wave Select';
            app.DeltaButton = uiradiobutton(app.WaveSelectButtonGroup);
            app.DeltaButton.Text = 'Delta';
            app.DeltaButton.Value = true;
            app.SineButton = uiradiobutton(app.WaveSelectButtonGroup);
            app.SineButton.Text = 'Sine';
            app.RectangleButton = uiradiobutton(app.WaveSelectButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.GaussButton = uiradiobutton(app.WaveSelectButtonGroup);
            app.GaussButton.Text = 'Gauss';
            app.GaborButton = uiradiobutton(app.WaveSelectButtonGroup);
            app.GaborButton.Text = 'Gabor';
            app.Position_xLabel = uilabel(app.LeftPanel);
            app.Position_xLabel.Text = 'Position_x';
            app.Slider_x = uislider(app.LeftPanel);
            app.Slider_x.Limits = [1 256];
            app.Slider_x.MajorTicks = [1 64 128 192 256];
            app.Slider_x.MinorTicks = [];
            app.Slider_x.Value = 128;
            app.Position_yLabel = uilabel(app.LeftPanel);
            app.Position_yLabel.Text = 'Position_y';
            app.Slider_y = uislider(app.LeftPanel);
            app.Slider_y.Limits = [1 256];
            app.Slider_y.MajorTicks = [1 64 128 192 256];
            app.Slider_y.MinorTicks = [];
            app.Slider_y.Value = 128;
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider2Label = uilabel(app.LeftPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.LeftPanel);
            app.Slider3Label = uilabel(app.LeftPanel);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_origin_2 = uiimage(app.RightPanel);
            app.Image_ampli_2 = uiimage(app.RightPanel);
            app.Image_phase_2 = uiimage(app.RightPanel);
            app.Image_orgin_3 = uiimage(app.RightPanel);
            app.Image_ampli_3 = uiimage(app.RightPanel);
            app.Image_phase_3 = uiimage(app.RightPanel);
            app.OriginFigureLabel = uilabel(app.RightPanel);
            app.OriginFigureLabel.Text = 'Origin Figure';
            app.PhaseFigureLabel = uilabel(app.RightPanel);
            app.PhaseFigureLabel.Text = 'Phase Figure';
            app.AmplitudeFigureLabel = uilabel(app.RightPanel);
            app.AmplitudeFigureLabel.Text = 'Amplitude Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DFT_2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end